classdef App_Forward_Kinematics < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Panel               matlab.ui.container.Panel
        Theta6Spinner       matlab.ui.control.Spinner
        Theta6SpinnerLabel  matlab.ui.control.Label
        Theta5Spinner       matlab.ui.control.Spinner
        Theta5SpinnerLabel  matlab.ui.control.Label
        Theta4Spinner       matlab.ui.control.Spinner
        Theta4SpinnerLabel  matlab.ui.control.Label
        Theta3Spinner       matlab.ui.control.Spinner
        Theta3SpinnerLabel  matlab.ui.control.Label
        Theta2Spinner       matlab.ui.control.Spinner
        Theta2SpinnerLabel  matlab.ui.control.Label
        Theta1Spinner       matlab.ui.control.Spinner
        Theta1SpinnerLabel  matlab.ui.control.Label
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        th1; % Description
        th2;
        th3;
        th4;
        th5;
        th6;
        ax;
        world;
    end
    
    methods (Access = private)
        
        function setAngle(app, joint, angle)
            joints = ["Joint1_Transform","Joint2_Transform",...
                      "Joint3_Transform","Joint4_Transform",...
                      "Joint5_Transform","Joint6_Transform"];
            app.world = setfield(app.world,joints(joint),'rotation',[0 1 0 angle]);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.th1 = 0; app.th2 = 0;
            app.th3 = 0; app.th4 = 0;
            app.th5 = 0; app.th6 = 0;

            app.ax = app.UIAxes;
            showRobot(app.ax,[app.th1 app.th2 app.th3 app.th4 app.th5 app.th6]);
            
            app.world = vrworld('Robot_World.WRL');
            open(app.world);
            myCanvas = vr.canvas(app.world);
            setAngle(app, 1:6, 0);
        end

        % Value changing function: Theta1Spinner
        function Theta1SpinnerValueChanging(app, event)
            changingValue = event.Value;
            
            app.th1 = 2*pi/360*changingValue;
            setAngle(app, 1 ,app.th1);
            showRobot(app.ax,[app.th1 app.th2 app.th3 app.th4 app.th5 app.th6]);
        end

        % Value changing function: Theta2Spinner
        function Theta2SpinnerValueChanging(app, event)
            changingValue = event.Value;
            
            app.th2 = 2*pi/360*changingValue;
            setAngle(app, 2, -app.th2);
            showRobot(app.ax,[app.th1 app.th2 app.th3 app.th4 app.th5 app.th6]);
        end

        % Value changing function: Theta3Spinner
        function Theta3SpinnerValueChanging(app, event)
            changingValue = event.Value;
            
            app.th3 = 2*pi/360*changingValue;
            setAngle(app, 3, app.th3);
            showRobot(app.ax,[app.th1 app.th2 app.th3 app.th4 app.th5 app.th6]);
        end

        % Value changing function: Theta4Spinner
        function Theta4SpinnerValueChanging(app, event)
            changingValue = event.Value;
            
            app.th4 = 2*pi/360*changingValue;
            setAngle(app, 4, -app.th4);
            showRobot(app.ax,[app.th1 app.th2 app.th3 app.th4 app.th5 app.th6]);
        end

        % Value changing function: Theta5Spinner
        function Theta5SpinnerValueChanging(app, event)
            changingValue = event.Value;
            
            app.th5 = 2*pi/360*changingValue;
            setAngle(app, 5, -app.th5);
            showRobot(app.ax,[app.th1 app.th2 app.th3 app.th4 app.th5 app.th6]);
        end

        % Value changing function: Theta6Spinner
        function Theta6SpinnerValueChanging(app, event)
            changingValue = event.Value;
            
            app.th6 = 2*pi/360*changingValue;
            setAngle(app, 6, app.th6);
            showRobot(app.ax,[app.th1 app.th2 app.th3 app.th4 app.th5 app.th6]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Theta1SpinnerLabel = uilabel(app.UIFigure);
            app.Theta1SpinnerLabel.Text = 'Theta 1';
            app.Theta1Spinner = uispinner(app.UIFigure);
            app.Theta1Spinner.Step = 5;
            app.Theta1Spinner.ValueChangingFcn = createCallbackFcn(app, @Theta1SpinnerValueChanging, true);
            app.Theta1Spinner.Limits = [-360 360];
            app.Theta2SpinnerLabel = uilabel(app.UIFigure);
            app.Theta2SpinnerLabel.Text = 'Theta 2';
            app.Theta2Spinner = uispinner(app.UIFigure);
            app.Theta2Spinner.Step = 5;
            app.Theta2Spinner.ValueChangingFcn = createCallbackFcn(app, @Theta2SpinnerValueChanging, true);
            app.Theta2Spinner.Limits = [-360 360];
            app.Theta3SpinnerLabel = uilabel(app.UIFigure);
            app.Theta3SpinnerLabel.Text = 'Theta 3';
            app.Theta3Spinner = uispinner(app.UIFigure);
            app.Theta3Spinner.Step = 5;
            app.Theta3Spinner.ValueChangingFcn = createCallbackFcn(app, @Theta3SpinnerValueChanging, true);
            app.Theta3Spinner.Limits = [-360 360];
            app.Theta4SpinnerLabel = uilabel(app.UIFigure);
            app.Theta4SpinnerLabel.Text = 'Theta 4';
            app.Theta4Spinner = uispinner(app.UIFigure);
            app.Theta4Spinner.Step = 5;
            app.Theta4Spinner.ValueChangingFcn = createCallbackFcn(app, @Theta4SpinnerValueChanging, true);
            app.Theta4Spinner.Limits = [-360 360];
            app.Theta5SpinnerLabel = uilabel(app.UIFigure);
            app.Theta5SpinnerLabel.Text = 'Theta 5';
            app.Theta5Spinner = uispinner(app.UIFigure);
            app.Theta5Spinner.Step = 5;
            app.Theta5Spinner.ValueChangingFcn = createCallbackFcn(app, @Theta5SpinnerValueChanging, true);
            app.Theta5Spinner.Limits = [-360 360];
            app.Theta6SpinnerLabel = uilabel(app.UIFigure);
            app.Theta6SpinnerLabel.Text = 'Theta 6';
            app.Theta6Spinner = uispinner(app.UIFigure);
            app.Theta6Spinner.Step = 5;
            app.Theta6Spinner.ValueChangingFcn = createCallbackFcn(app, @Theta6SpinnerValueChanging, true);
            app.Theta6Spinner.Limits = [-360 360];
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_Forward_Kinematics

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end